classdef DirectoryContents < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout       matlab.ui.container.GridLayout
        dividerPanel         matlab.ui.container.Panel
        fileFilters          matlab.ui.control.DropDown
        FilterDropDownLabel  matlab.ui.control.Label
        bioFormats           matlab.ui.control.CheckBox
        updateFileList       matlab.ui.control.Button
        help                 matlab.ui.control.Button
        fileList             matlab.ui.control.ListBox
    end

    properties (Access = public)
        handles % structure with handles of the component widgets
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            comp.handles.mainGridLayout = comp.mainGridLayout;
            comp.handles.fileList = comp.fileList;
            comp.handles.help = comp.help;
            comp.handles.updateFileList = comp.updateFileList;
            comp.handles.bioFormats = comp.bioFormats;
            comp.handles.fileFilters = comp.fileFilters;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 265 450];
            comp.mainGridLayout = uigridlayout(comp);
            comp.fileList = uilistbox(comp.mainGridLayout);
            comp.fileList.Multiselect = 'on';
            comp.fileList.Value = {'Item 1'};
            comp.help = uibutton(comp.mainGridLayout, 'push');
            comp.help.Tag = 'dirContentsHelp';
            comp.help.Icon = 'help_16px.png';
            comp.help.Text = '';
            comp.updateFileList = uibutton(comp.mainGridLayout, 'push');
            comp.updateFileList.Text = 'Update';
            comp.bioFormats = uicheckbox(comp.mainGridLayout);
            comp.bioFormats.Text = 'Bio';
            comp.FilterDropDownLabel = uilabel(comp.mainGridLayout);
            comp.FilterDropDownLabel.Text = 'Filter';
            comp.fileFilters = uidropdown(comp.mainGridLayout);
            comp.fileFilters.Items = {'all known', 'jpg', 'tif'};
            comp.fileFilters.Value = 'all known';
            comp.dividerPanel = uipanel(comp.mainGridLayout);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end