classdef SelectionViewSettings < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        mainGridLayout         matlab.ui.container.GridLayout
        rightDividerPanel      matlab.ui.container.Panel
        leftDividerPanel       matlab.ui.container.Panel
        rightGridLayout        matlab.ui.container.GridLayout
        help                   matlab.ui.control.Button
        transparencyLabel      matlab.ui.control.Label
        maskTransparencyLabel  matlab.ui.control.Label
        selectionTransparency  matlab.ui.control.Slider
        maskTransparency       matlab.ui.control.Slider
        MaskSliderLabel        matlab.ui.control.Label
        modelTransparency      matlab.ui.control.Slider
        ModelSliderLabel       matlab.ui.control.Label
        centralGridLayout      matlab.ui.container.GridLayout
        onFly                  matlab.ui.control.CheckBox
        display                matlab.ui.control.Button
        hideImage              matlab.ui.control.CheckBox
        showAnnotations        matlab.ui.control.CheckBox
        showMask               matlab.ui.control.CheckBox
        showModel              matlab.ui.control.CheckBox
        lutTable               matlab.ui.control.Table
        lutColors              matlab.ui.control.CheckBox
        leftGridLayout         matlab.ui.container.GridLayout
        applySegmentationIn3D  matlab.ui.control.CheckBox
        differenceSelection    matlab.ui.control.CheckBox
        strel                  matlab.ui.control.EditField
        StrelEditFieldLabel    matlab.ui.control.Label
        dilate                 matlab.ui.control.Button
        erode                  matlab.ui.control.Button
        colChannel             matlab.ui.control.DropDown
        colorChannelLabel      matlab.ui.control.Label
        autoFillSelection      matlab.ui.control.CheckBox
        toolPresetLabel        matlab.ui.control.Label
        preset3                matlab.ui.control.Button
        preset2                matlab.ui.control.Button
        preset1                matlab.ui.control.Button
        fill                   matlab.ui.control.Button
        clear                  matlab.ui.control.Button
        replace                matlab.ui.control.Button
        subtract               matlab.ui.control.Button
        add                    matlab.ui.control.Button
    end

    properties (Access = public)
        handles % handles to the component widgets
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % allocate widget handles to the handles structure
            comp.handles.mainGridLayout = comp.mainGridLayout;
            comp.handles.add = comp.add;
            comp.handles.subtract = comp.subtract;
            comp.handles.replace = comp.replace;
            comp.handles.clear = comp.clear;
            comp.handles.fill = comp.fill;

            comp.handles.colChannel = comp.colChannel;
            comp.handles.autoFillSelection = comp.autoFillSelection;
            comp.handles.applySegmentationIn3D = comp.applySegmentationIn3D;
            
            comp.handles.preset1 = comp.preset1;
            comp.handles.preset2 = comp.preset2;
            comp.handles.preset3 = comp.preset3;
            
            comp.handles.erode = comp.erode;
            comp.handles.differenceSelection = comp.differenceSelection;
            comp.handles.dilate = comp.dilate;
            comp.handles.strel = comp.strel;

            comp.handles.lutTable = comp.lutTable;
            comp.handles.lutColors = comp.lutColors;
            comp.handles.showModel = comp.showModel;
            comp.handles.showMask = comp.showMask;
            comp.handles.showAnnotations = comp.showAnnotations;
            comp.handles.hideImage = comp.hideImage;
            comp.handles.display = comp.display;
            comp.handles.onFly = comp.onFly;

            comp.handles.modelTransparency = comp.modelTransparency;
            comp.handles.maskTransparency = comp.maskTransparency;
            comp.handles.selectionTransparency = comp.selectionTransparency;
            comp.handles.help = comp.help;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 910 147];
            comp.mainGridLayout = uigridlayout(comp);
            comp.leftGridLayout = uigridlayout(comp.mainGridLayout);
            comp.add = uibutton(comp.leftGridLayout, 'push');
            comp.add.Tag = 'add';
            comp.add.Icon = 'add_16px.png';
            comp.add.Tooltip = {'Add selection to Mask or Model'};
            comp.add.Text = '(A)dd';
            comp.subtract = uibutton(comp.leftGridLayout, 'push');
            comp.subtract.Tag = 'subtract';
            comp.subtract.Icon = 'remove_16px.png';
            comp.subtract.Tooltip = {'Subtract selection from Mask or Model'};
            comp.subtract.Text = '(S)ubtract';
            comp.replace = uibutton(comp.leftGridLayout, 'push');
            comp.replace.Tag = 'replace';
            comp.replace.Icon = 'replace_16px.png';
            comp.replace.Tooltip = {'Replace the selected material or mask with the current selection'};
            comp.replace.Text = '(R)eplace';
            comp.clear = uibutton(comp.leftGridLayout, 'push');
            comp.clear.Tag = 'clear';
            comp.clear.Icon = 'clear_16px.png';
            comp.clear.Tooltip = {'Clear selection'};
            comp.clear.Text = '(C)lear';
            comp.fill = uibutton(comp.leftGridLayout, 'push');
            comp.fill.Tag = 'fill';
            comp.fill.Icon = 'fill_16px.png';
            comp.fill.Tooltip = {'Fill holdes in the selection layer'};
            comp.fill.Text = '(F)ill';
            comp.preset1 = uibutton(comp.leftGridLayout, 'push');
            comp.preset1.Tag = 'preset1';
            comp.preset1.Tooltip = {'Stores the current segmentation tool configuration (Shift+Click or Shift+1), or restores a previously saved configuration (Click or press 1)'};
            comp.preset1.Text = '1';
            comp.preset2 = uibutton(comp.leftGridLayout, 'push');
            comp.preset2.Tag = 'preset2';
            comp.preset2.Tooltip = {'Stores the current segmentation tool configuration (Shift+Click or Shift+2), or restores a previously saved configuration (Click or press 2)'};
            comp.preset2.Text = '2';
            comp.preset3 = uibutton(comp.leftGridLayout, 'push');
            comp.preset3.Tag = 'preset3';
            comp.preset3.Tooltip = {'Stores the current segmentation tool configuration (Shift+Click or Shift+3), or restores a previously saved configuration (Click or press 3)'};
            comp.preset3.Text = '3';
            comp.toolPresetLabel = uilabel(comp.leftGridLayout);
            comp.toolPresetLabel.Text = 'Tool presets';
            comp.autoFillSelection = uicheckbox(comp.leftGridLayout);
            comp.autoFillSelection.Tag = 'autoFillSelection';
            comp.autoFillSelection.Tooltip = {'autofill selected areas to remove holes'};
            comp.autoFillSelection.Text = 'Auto fill selection';
            comp.autoFillSelection.Value = true;
            comp.colorChannelLabel = uilabel(comp.leftGridLayout);
            comp.colorChannelLabel.Text = 'Color channel';
            comp.colChannel = uidropdown(comp.leftGridLayout);
            comp.colChannel.Items = {'All', 'Ch 1', 'Ch 2', 'Ch 3'};
            comp.colChannel.Tag = 'colChannel';
            comp.colChannel.Value = 'All';
            comp.erode = uibutton(comp.leftGridLayout, 'push');
            comp.erode.Tag = 'erode';
            comp.erode.Text = 'Erode';
            comp.dilate = uibutton(comp.leftGridLayout, 'push');
            comp.dilate.Tag = 'dilate';
            comp.dilate.Text = 'Dilate';
            comp.StrelEditFieldLabel = uilabel(comp.leftGridLayout);
            comp.StrelEditFieldLabel.Text = 'Strel';
            comp.strel = uieditfield(comp.leftGridLayout, 'text');
            comp.strel.Tag = 'strel';
            comp.strel.Value = '3';
            comp.differenceSelection = uicheckbox(comp.leftGridLayout);
            comp.differenceSelection.Tag = 'differenceSelection';
            comp.differenceSelection.Text = 'difference';
            comp.applySegmentationIn3D = uicheckbox(comp.leftGridLayout);
            comp.applySegmentationIn3D.Tag = 'applySegmentationIn3D';
            comp.applySegmentationIn3D.Tooltip = {'Apply some of the segmentation tools in 3D'};
            comp.applySegmentationIn3D.Text = 'Apply in 3D';
            comp.centralGridLayout = uigridlayout(comp.mainGridLayout);
            comp.lutColors = uicheckbox(comp.centralGridLayout);
            comp.lutColors.Tag = 'lutColors';
            comp.lutColors.Text = 'LUT colors';
            comp.lutTable = uitable(comp.centralGridLayout);
            comp.lutTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            comp.lutTable.RowName = {};
            comp.lutTable.Tag = 'lutTable';
            comp.showModel = uicheckbox(comp.centralGridLayout);
            comp.showModel.Tag = 'showModel';
            comp.showModel.Text = 'Show model';
            comp.showMask = uicheckbox(comp.centralGridLayout);
            comp.showMask.Tag = 'showMask';
            comp.showMask.Text = 'Show mask';
            comp.showAnnotations = uicheckbox(comp.centralGridLayout);
            comp.showAnnotations.Tag = 'showAnnotations';
            comp.showAnnotations.Text = 'Ann/Measure';
            comp.hideImage = uicheckbox(comp.centralGridLayout);
            comp.hideImage.Tag = 'hideImage';
            comp.hideImage.Text = 'Hide image';
            comp.display = uibutton(comp.centralGridLayout, 'push');
            comp.display.Tag = 'display';
            comp.display.Icon = 'screen_16px.png';
            comp.display.Text = 'Display';
            comp.onFly = uicheckbox(comp.centralGridLayout);
            comp.onFly.Tag = 'onFly';
            comp.onFly.Text = 'on-fly';
            comp.rightGridLayout = uigridlayout(comp.mainGridLayout);
            comp.ModelSliderLabel = uilabel(comp.rightGridLayout);
            comp.ModelSliderLabel.Text = 'Model';
            comp.modelTransparency = uislider(comp.rightGridLayout);
            comp.modelTransparency.Limits = [0 1];
            comp.modelTransparency.MajorTicks = [0 25 50 75 100];
            comp.modelTransparency.MajorTickLabels = {''};
            comp.modelTransparency.MinorTicks = [0 100];
            comp.modelTransparency.Step = 0.01;
            comp.modelTransparency.Tag = 'modelTransparency';
            comp.modelTransparency.Value = 0.75;
            comp.MaskSliderLabel = uilabel(comp.rightGridLayout);
            comp.MaskSliderLabel.Text = 'Mask';
            comp.maskTransparency = uislider(comp.rightGridLayout);
            comp.maskTransparency.Limits = [0 1];
            comp.maskTransparency.MajorTicks = [0 25 50 75 100];
            comp.maskTransparency.MajorTickLabels = {''};
            comp.maskTransparency.MinorTicks = [0 100];
            comp.maskTransparency.Step = 0.01;
            comp.maskTransparency.Tag = 'maskTransparency';
            comp.selectionTransparency = uislider(comp.rightGridLayout);
            comp.selectionTransparency.Limits = [0 1];
            comp.selectionTransparency.MajorTicks = [0 25 50 75 100];
            comp.selectionTransparency.MajorTickLabels = {''};
            comp.selectionTransparency.MinorTicks = [0 100];
            comp.selectionTransparency.Step = 0.01;
            comp.selectionTransparency.Tag = 'selectionTransparency';
            comp.selectionTransparency.Value = 0.75;
            comp.maskTransparencyLabel = uilabel(comp.rightGridLayout);
            comp.maskTransparencyLabel.Text = 'Selection';
            comp.transparencyLabel = uilabel(comp.rightGridLayout);
            comp.transparencyLabel.Text = 'Transparency';
            comp.help = uibutton(comp.rightGridLayout, 'push');
            comp.help.Icon = 'help_16px.png';
            comp.help.Text = '';
            comp.leftDividerPanel = uipanel(comp.mainGridLayout);
            comp.leftDividerPanel.Tag = 'leftDividerPanel';
            comp.rightDividerPanel = uipanel(comp.mainGridLayout);
            comp.rightDividerPanel.Tag = 'rightDividerPanel';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end