classdef AmiraImportGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gui                          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        importParametersPanel        matlab.ui.container.Panel
        gridLayout2                  matlab.ui.container.GridLayout
        firstLabel                   matlab.ui.control.Label
        secondLabel                  matlab.ui.control.Label
        datasetDimensionsLabel       matlab.ui.control.Label
        StartsliceSpinnerLabel       matlab.ui.control.Label
        startSliceSlice              matlab.ui.control.Spinner
        binZSpinner                  matlab.ui.control.Spinner
        StartsliceSpinner_2Label_2   matlab.ui.control.Label
        ResizeXYmethodDropDownLabel  matlab.ui.control.Label
        resizeDropdown               matlab.ui.control.DropDown
        binXYSpinner                 matlab.ui.control.Spinner
        StartsliceSpinner_2Label     matlab.ui.control.Label
        zStepSpinner                 matlab.ui.control.Spinner
        ZstepLabel                   matlab.ui.control.Label
        endSliceSpinner              matlab.ui.control.Spinner
        EndsliceLabel                matlab.ui.control.Label
        cancelBtn                    matlab.ui.control.Button
        continueBtn                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        handles % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Figure.Visible = false;

            app.handles.firstLabel = app.firstLabel;
            app.handles.datasetDimensionsLabel = app.datasetDimensionsLabel;
            app.handles.startSliceSlice = app.startSliceSlice;
            app.handles.zStepSpinner = app.zStepSpinner;
            app.handles.endSliceSpinner = app.endSliceSpinner;
            app.handles.binXYSpinner = app.binXYSpinner;
            app.handles.binZSpinner = app.binZSpinner;
            app.handles.resizeDropdown = app.resizeDropdown;
            app.handles.cancelBtn = app.cancelBtn;
            app.handles.continueBtn = app.continueBtn;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gui = uifigure('Visible', 'off');
            app.gui.Name = 'Amira import';
            app.GridLayout = uigridlayout(app.gui);
            app.continueBtn = uibutton(app.GridLayout, 'push');
            app.continueBtn.Text = 'Continue';
            app.cancelBtn = uibutton(app.GridLayout, 'push');
            app.cancelBtn.Text = 'Cancel';
            app.importParametersPanel = uipanel(app.GridLayout);
            app.importParametersPanel.Title = 'Import parameters';
            app.gridLayout2 = uigridlayout(app.importParametersPanel);
            app.EndsliceLabel = uilabel(app.gridLayout2);
            app.EndsliceLabel.Text = 'End slice';
            app.endSliceSpinner = uispinner(app.gridLayout2);
            app.endSliceSpinner.Limits = [1 Inf];
            app.endSliceSpinner.RoundFractionalValues = 'on';
            app.endSliceSpinner.ValueDisplayFormat = '%d';
            app.endSliceSpinner.Value = 1;
            app.ZstepLabel = uilabel(app.gridLayout2);
            app.ZstepLabel.Text = 'Z step';
            app.zStepSpinner = uispinner(app.gridLayout2);
            app.zStepSpinner.Limits = [1 Inf];
            app.zStepSpinner.RoundFractionalValues = 'on';
            app.zStepSpinner.ValueDisplayFormat = '%d';
            app.zStepSpinner.Value = 10;
            app.StartsliceSpinner_2Label = uilabel(app.gridLayout2);
            app.StartsliceSpinner_2Label.Text = 'Bin XY dimension, times';
            app.binXYSpinner = uispinner(app.gridLayout2);
            app.binXYSpinner.Limits = [1 Inf];
            app.binXYSpinner.RoundFractionalValues = 'on';
            app.binXYSpinner.ValueDisplayFormat = '%d';
            app.binXYSpinner.Value = 2;
            app.resizeDropdown = uidropdown(app.gridLayout2);
            app.resizeDropdown.Items = {'nearest', 'bilinear', 'bicubic'};
            app.resizeDropdown.Value = 'bicubic';
            app.ResizeXYmethodDropDownLabel = uilabel(app.gridLayout2);
            app.ResizeXYmethodDropDownLabel.Text = 'Resize XY method';
            app.StartsliceSpinner_2Label_2 = uilabel(app.gridLayout2);
            app.StartsliceSpinner_2Label_2.Text = 'Bin Z dimension, times';
            app.binZSpinner = uispinner(app.gridLayout2);
            app.binZSpinner.Limits = [1 Inf];
            app.binZSpinner.RoundFractionalValues = 'on';
            app.binZSpinner.ValueDisplayFormat = '%d';
            app.binZSpinner.Value = 1;
            app.startSliceSlice = uispinner(app.gridLayout2);
            app.startSliceSlice.Limits = [1 Inf];
            app.startSliceSlice.RoundFractionalValues = 'on';
            app.startSliceSlice.ValueDisplayFormat = '%d';
            app.startSliceSlice.Value = 1;
            app.StartsliceSpinnerLabel = uilabel(app.gridLayout2);
            app.StartsliceSpinnerLabel.Text = 'Start slice';
            app.datasetDimensionsLabel = uilabel(app.gridLayout2);
            app.datasetDimensionsLabel.Text = 'width x height x thickness';
            app.secondLabel = uilabel(app.gridLayout2);
            app.secondLabel.Text = 'The selected dataset has the following dimensions (w:h:z):';
            app.firstLabel = uilabel(app.gridLayout2);
            app.firstLabel.Text = 'Define paramters for importing Amira Mesh file';
            % Show the figure after all components are created
            app.gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AmiraImportGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gui)
        end
    end
end