classdef DisplayAdjustGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure              matlab.ui.Figure
        mainGridLayout      matlab.ui.container.GridLayout
        colorChannelPanel1  matlab.ui.container.Panel
        adjHelpBtn          matlab.ui.control.Button
        ColorDropDownLabel  matlab.ui.control.Label
        colorChannelCombo   matlab.ui.control.DropDown
        MinSpinnerLabel     matlab.ui.control.Label
        minEdit             matlab.ui.control.Spinner
        findMinBtn          matlab.ui.control.Button
        minSlider           matlab.ui.control.Slider
        MaxSpinnerLabel     matlab.ui.control.Label
        maxEdit             matlab.ui.control.Spinner
        findMaxBtn          matlab.ui.control.Button
        maxSlider           matlab.ui.control.Slider
        GammaSpinnerLabel   matlab.ui.control.Label
        gammaEdit           matlab.ui.control.Spinner
        linkChannelsCheck   matlab.ui.control.CheckBox
        gammaSlider         matlab.ui.control.Slider
        logViewCheck        matlab.ui.control.CheckBox
        autoHistCheck       matlab.ui.control.CheckBox
        updateBtn           matlab.ui.control.Button
        adjustPanel         matlab.ui.container.Panel
        applyBtn            matlab.ui.control.Button
        stretchCurrent      matlab.ui.control.Button
        imHist              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        winController % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            app.Figure.Name = 'Display adjustment';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.imHist = uiaxes(app.mainGridLayout);
            app.imHist.Tag = 'imHist';
            app.adjustPanel = uipanel(app.mainGridLayout);
            app.adjustPanel.Title = 'Adjust channel 1';
            app.adjustPanel.Tag = 'adjustPanel';
            app.stretchCurrent = uibutton(app.adjustPanel, 'push');
            app.stretchCurrent.Tag = 'stretchCurrent';
            app.stretchCurrent.Tooltip = {'Stretch the intensity range of the current slice using the selected Min and Max values'};
            app.stretchCurrent.Text = 'Current slice';
            app.applyBtn = uibutton(app.adjustPanel, 'push');
            app.applyBtn.Tag = 'applyBtn';
            app.applyBtn.Tooltip = {'Stretch the intensity range of all slices using the selected Min and Max values'};
            app.applyBtn.Text = 'All slices';
            app.updateBtn = uibutton(app.mainGridLayout, 'push');
            app.updateBtn.Tag = 'updateBtn';
            app.updateBtn.Tooltip = {'Update the histogram'};
            app.updateBtn.Text = 'Update';
            app.autoHistCheck = uicheckbox(app.mainGridLayout);
            app.autoHistCheck.Tag = 'autoHistCheck';
            app.autoHistCheck.Tooltip = {'Automatically refresh the histogram when the slice changes'};
            app.autoHistCheck.Text = 'Auto update histogram';
            app.logViewCheck = uicheckbox(app.mainGridLayout);
            app.logViewCheck.Tag = 'logViewCheck';
            app.logViewCheck.Tooltip = {'Display the histogram with a logarithmic scale on the Y-axis'};
            app.logViewCheck.Text = 'Log scale';
            app.gammaSlider = uislider(app.mainGridLayout);
            app.gammaSlider.Limits = [0.001 5];
            app.gammaSlider.Step = 0.001;
            app.gammaSlider.Tag = 'gammaSlider';
            app.gammaSlider.Tooltip = {'Adjust the gamma correction point to control midtone display'};
            app.gammaSlider.Value = 0.001;
            app.linkChannelsCheck = uicheckbox(app.mainGridLayout);
            app.linkChannelsCheck.Tag = 'linkChannelsCheck';
            app.linkChannelsCheck.Tooltip = {'link all shown channels'};
            app.linkChannelsCheck.Text = 'link';
            app.linkChannelsCheck.Value = true;
            app.gammaEdit = uispinner(app.mainGridLayout);
            app.gammaEdit.Step = 0.1;
            app.gammaEdit.Limits = [0 Inf];
            app.gammaEdit.ValueDisplayFormat = '%11.3f';
            app.gammaEdit.Tag = 'gammaEdit';
            app.gammaEdit.Tooltip = {'Adjust the gamma correction point to control midtone display'};
            app.gammaEdit.Value = 1;
            app.GammaSpinnerLabel = uilabel(app.mainGridLayout);
            app.GammaSpinnerLabel.Text = 'Gamma';
            app.maxSlider = uislider(app.mainGridLayout);
            app.maxSlider.Tag = 'maxSlider';
            app.maxSlider.Tooltip = {'Adjust the white point for visualization'};
            app.findMaxBtn = uibutton(app.mainGridLayout, 'push');
            app.findMaxBtn.Tag = 'findMaxBtn';
            app.findMaxBtn.Tooltip = {'Detect the maximum intensity and stretch the dataset range to it; right click for additional options'};
            app.findMaxBtn.Text = 'Max';
            app.maxEdit = uispinner(app.mainGridLayout);
            app.maxEdit.RoundFractionalValues = 'on';
            app.maxEdit.ValueDisplayFormat = '%11d';
            app.maxEdit.Tag = 'maxEdit';
            app.maxEdit.Tooltip = {'Adjust the white point for visualization'};
            app.maxEdit.Value = 255;
            app.MaxSpinnerLabel = uilabel(app.mainGridLayout);
            app.MaxSpinnerLabel.Text = 'Max';
            app.minSlider = uislider(app.mainGridLayout);
            app.minSlider.Tag = 'minSlider';
            app.minSlider.Tooltip = {'Adjust the black point for visualization'};
            app.findMinBtn = uibutton(app.mainGridLayout, 'push');
            app.findMinBtn.Tag = 'findMinBtn';
            app.findMinBtn.Tooltip = {'Detect the minimum intensity and stretch the dataset range to it; right click for additional options'};
            app.findMinBtn.Text = 'Min';
            app.minEdit = uispinner(app.mainGridLayout);
            app.minEdit.RoundFractionalValues = 'on';
            app.minEdit.ValueDisplayFormat = '%11d';
            app.minEdit.Tag = 'minEdit';
            app.minEdit.Tooltip = {'Adjust the black point for visualization'};
            app.MinSpinnerLabel = uilabel(app.mainGridLayout);
            app.MinSpinnerLabel.Text = 'Min';
            app.colorChannelCombo = uidropdown(app.mainGridLayout);
            app.colorChannelCombo.Tag = 'colorChannelCombo';
            app.colorChannelCombo.Tooltip = {'Select color channel'};
            app.ColorDropDownLabel = uilabel(app.mainGridLayout);
            app.ColorDropDownLabel.Text = 'Color';
            app.adjHelpBtn = uibutton(app.mainGridLayout, 'push');
            app.adjHelpBtn.Tag = 'adjHelpBtn';
            app.adjHelpBtn.Icon = 'help_16px.png';
            app.adjHelpBtn.Text = '';
            app.colorChannelPanel1 = uipanel(app.mainGridLayout);
            app.colorChannelPanel1.Tag = 'colorChannelPanel1';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayAdjustGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end