classdef ImageFiltersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        mainGridLayout               matlab.ui.container.GridLayout
        OptionsPanel                 matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UseParallelComputing         matlab.ui.control.CheckBox
        Mode3D                       matlab.ui.control.CheckBox
        MaterialIndex                matlab.ui.control.EditField
        MaterialindexEditFieldLabel  matlab.ui.control.Label
        ColorChannel                 matlab.ui.control.DropDown
        ColorchannelLabel            matlab.ui.control.Label
        SourceLayer                  matlab.ui.control.DropDown
        SourcelayerLabel             matlab.ui.control.Label
        DatasetType                  matlab.ui.control.DropDown
        DatasettypeLabel_2           matlab.ui.control.Label
        FilterparametersPanel        matlab.ui.container.Panel
        GridLayoutParameters         matlab.ui.container.GridLayout
        FilterselectionPanel         matlab.ui.container.Panel
        FilterGroup                  matlab.ui.control.DropDown
        FiltergroupLabel             matlab.ui.control.Label
        InfoHTML                     matlab.ui.control.HTML
        FilterinfoLabel              matlab.ui.control.Label
        FilterName                   matlab.ui.control.DropDown
        FilternameLabel              matlab.ui.control.Label
        ThumbnailView1               matlab.ui.control.UIAxes
        ThumbnailView2               matlab.ui.control.UIAxes
        buttonsGridLayout            matlab.ui.container.GridLayout
        HelpButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        PreviewButton                matlab.ui.control.Button
        AutopreviewCheckBox          matlab.ui.control.CheckBox
        ActionToResult               matlab.ui.control.DropDown
        Label_4                      matlab.ui.control.Label
        FilterButton                 matlab.ui.control.Button
    end

    properties (Access = public)
        winController % controller of the view
        isShiftPressed = false % logical switch indicating the state of shift
        isControlPressed = false % logical switch indicating the state of control
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        function Parameter_Callback(app, hObject, eventdata, handles)
            % callback for multiple widgets of GUI to update BatchOpt
            % this function handles updates of BatchOpt structure in the controller
            
            app.winController.updateBatchOptFromGUI(hObject);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end

        % Close request function: Figure
        function FigureCloseRequest(app, event)
            app.winController.closeWindow();
        end

        % Key press function: Figure
        function FigureKeyPress(app, event)
            % add a key press callback to remove the default Ctrl+Z callback 

            % call key press callback of MIB main window
            % eventData = struct();
            % if isempty(event.Character); return; end    % when only modifiers are pressed do not trigger the shortcuts
            % eventData.eventdata = event;
            % eventData = ToggleEventData(eventData);
            % notify(app.winController.mibModel, 'KeyPressEvent', eventData);
        end

        % Window key press function: Figure
        function FigureWindowKeyPress(app, event)
            % update the states of shift/control pressed switches
            switch event.Key
                case 'shift'
                    app.isShiftPressed = true;
                case 'control'
                    app.isControlPressed = true;
            end
        end

        % Window key release function: Figure
        function FigureWindowKeyRelease(app, event)
            % update the states of shift/control pressed switches
            switch event.Key
                case 'shift'
                    app.isShiftPressed = false;
                case 'control'
                    app.isControlPressed = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Image filters';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.Figure.WindowKeyPressFcn = createCallbackFcn(app, @FigureWindowKeyPress, true);
            app.Figure.WindowKeyReleaseFcn = createCallbackFcn(app, @FigureWindowKeyRelease, true);
            app.Figure.KeyPressFcn = createCallbackFcn(app, @FigureKeyPress, true);
            app.mainGridLayout = uigridlayout(app.Figure);
            app.buttonsGridLayout = uigridlayout(app.mainGridLayout);
            app.FilterButton = uibutton(app.buttonsGridLayout, 'push');
            app.FilterButton.Text = 'Filter';
            app.Label_4 = uilabel(app.buttonsGridLayout);
            app.Label_4.Text = '';
            app.ActionToResult = uidropdown(app.buttonsGridLayout);
            app.ActionToResult.Items = {'Filter image', 'Filter and subtract', 'Filter and add'};
            app.ActionToResult.Value = 'Filter image';
            app.AutopreviewCheckBox = uicheckbox(app.buttonsGridLayout);
            app.AutopreviewCheckBox.Text = 'Auto preview';
            app.PreviewButton = uibutton(app.buttonsGridLayout, 'push');
            app.PreviewButton.Text = 'Preview';
            app.CloseButton = uibutton(app.buttonsGridLayout, 'push');
            app.CloseButton.Text = 'Close';
            app.HelpButton = uibutton(app.buttonsGridLayout, 'push');
            app.HelpButton.Icon = 'help_16px.png';
            app.HelpButton.Text = '';
            app.FilterselectionPanel = uipanel(app.mainGridLayout);
            app.FilterselectionPanel.Title = 'Filter selection';
            app.ThumbnailView2 = uiaxes(app.FilterselectionPanel);
            app.ThumbnailView2.XLim = [0 130];
            app.ThumbnailView2.YLim = [0 130];
            app.ThumbnailView2.XAxisLocation = 'origin';
            app.ThumbnailView2.XTick = [];
            app.ThumbnailView2.XTickLabelRotationMode = 'manual';
            app.ThumbnailView2.XTickLabel = '';
            app.ThumbnailView2.YAxisLocation = 'origin';
            app.ThumbnailView2.YTick = [];
            app.ThumbnailView2.YTickLabelRotationMode = 'manual';
            app.ThumbnailView2.YTickLabel = '';
            app.ThumbnailView2.ZTick = [];
            app.ThumbnailView2.ZTickLabelRotationMode = 'manual';
            app.ThumbnailView2.TickDirMode = 'manual';
            app.ThumbnailView1 = uiaxes(app.FilterselectionPanel);
            app.ThumbnailView1.XLim = [0 130];
            app.ThumbnailView1.YLim = [0 130];
            app.ThumbnailView1.XAxisLocation = 'origin';
            app.ThumbnailView1.XTick = [];
            app.ThumbnailView1.XTickLabelRotationMode = 'manual';
            app.ThumbnailView1.XTickLabel = '';
            app.ThumbnailView1.YAxisLocation = 'origin';
            app.ThumbnailView1.YTick = [];
            app.ThumbnailView1.YTickLabelRotationMode = 'manual';
            app.ThumbnailView1.YTickLabel = '';
            app.ThumbnailView1.ZTick = [];
            app.ThumbnailView1.ZTickLabelRotationMode = 'manual';
            app.ThumbnailView1.TickDirMode = 'manual';
            app.FilternameLabel = uilabel(app.FilterselectionPanel);
            app.FilternameLabel.Text = 'Filter name:';
            app.FilterName = uidropdown(app.FilterselectionPanel);
            app.FilterName.Items = {'Average', 'Disk'};
            app.FilterName.Value = 'Disk';
            app.FilterinfoLabel = uilabel(app.FilterselectionPanel);
            app.FilterinfoLabel.Text = 'Filter info:';
            app.InfoHTML = uihtml(app.FilterselectionPanel);
            app.FiltergroupLabel = uilabel(app.FilterselectionPanel);
            app.FiltergroupLabel.Text = 'Filter group:';
            app.FilterGroup = uidropdown(app.FilterselectionPanel);
            app.FilterGroup.Items = {'Basic Image Filtering in the Spatial Domain', 'Edge-Preserving Filtering'};
            app.FilterGroup.Value = 'Edge-Preserving Filtering';
            app.FilterparametersPanel = uipanel(app.mainGridLayout);
            app.FilterparametersPanel.Title = 'Filter parameters';
            app.GridLayoutParameters = uigridlayout(app.FilterparametersPanel);
            app.OptionsPanel = uipanel(app.mainGridLayout);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout2 = uigridlayout(app.OptionsPanel);
            app.DatasettypeLabel_2 = uilabel(app.GridLayout2);
            app.DatasettypeLabel_2.Text = 'Dataset type:';
            app.DatasetType = uidropdown(app.GridLayout2);
            app.DatasetType.Tooltip = {'Range of data to filter'};
            app.SourcelayerLabel = uilabel(app.GridLayout2);
            app.SourcelayerLabel.Text = 'Source layer:';
            app.SourceLayer = uidropdown(app.GridLayout2);
            app.SourceLayer.Items = {'image', 'selection', 'mask'};
            app.SourceLayer.Tooltip = {'Target layer'};
            app.SourceLayer.Value = 'image';
            app.ColorchannelLabel = uilabel(app.GridLayout2);
            app.ColorchannelLabel.Text = 'Color channel:';
            app.ColorChannel = uidropdown(app.GridLayout2);
            app.ColorChannel.Tooltip = {'Color channels for filtering'};
            app.MaterialindexEditFieldLabel = uilabel(app.GridLayout2);
            app.MaterialindexEditFieldLabel.Text = 'Material index:';
            app.MaterialIndex = uieditfield(app.GridLayout2, 'text');
            app.MaterialIndex.Tooltip = {'[Models only] Index of material for filtering'};
            app.MaterialIndex.Value = '1';
            app.Mode3D = uicheckbox(app.GridLayout2);
            app.Mode3D.Tooltip = {'Apply 3D filter, if possible'};
            app.Mode3D.Text = '3D';
            app.UseParallelComputing = uicheckbox(app.GridLayout2);
            app.UseParallelComputing.Text = 'use parallel';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageFiltersGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end