classdef Lines3dDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                    matlab.ui.Figure
        edgesViewAdditionalField  matlab.ui.control.DropDown
        edgesViewTable            matlab.ui.control.Table
        mainGridLayout            matlab.ui.container.GridLayout
        settingsBtn               matlab.ui.control.Button
        visualizeBtn              matlab.ui.control.Button
        deleteBtn                 matlab.ui.control.Button
        refreshBtn                matlab.ui.control.Button
        helpBtn                   matlab.ui.control.Button
        saveBtn                   matlab.ui.control.Button
        loadBtn                   matlab.ui.control.Button
        nodesViewTable            matlab.ui.control.Table
        autoRefreshCheck          matlab.ui.control.CheckBox
        jumpCheck                 matlab.ui.control.CheckBox
        nodesViewAdditionalField  matlab.ui.control.DropDown
        FieldDropDownLabel        matlab.ui.control.Label
        tableSelectionPopup       matlab.ui.control.DropDown
        TableDropDownLabel        matlab.ui.control.Label
        activeNodeText            matlab.ui.control.Label
        activeTreeText            matlab.ui.control.Label
        Treesof3DlinesLabel       matlab.ui.control.Label
        treesViewTable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        winController % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
            app.Figure.Name = '3D lines list';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'MATLAB App';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.treesViewTable = uitable(app.mainGridLayout);
            app.treesViewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.treesViewTable.RowName = {};
            app.treesViewTable.Tag = 'treesViewTable';
            app.Treesof3DlinesLabel = uilabel(app.mainGridLayout);
            app.Treesof3DlinesLabel.Text = 'Trees of 3D lines';
            app.activeTreeText = uilabel(app.mainGridLayout);
            app.activeTreeText.Tag = 'activeTreeText';
            app.activeTreeText.Tooltip = {'index of the currently active tree'};
            app.activeTreeText.Text = 'Active tree:';
            app.activeNodeText = uilabel(app.mainGridLayout);
            app.activeNodeText.Tag = 'activeNodeText';
            app.activeNodeText.Tooltip = {'index of the active node'};
            app.activeNodeText.Text = 'Active node:';
            app.TableDropDownLabel = uilabel(app.mainGridLayout);
            app.TableDropDownLabel.Text = 'Table';
            app.tableSelectionPopup = uidropdown(app.mainGridLayout);
            app.tableSelectionPopup.Tag = 'tableSelectionPopup';
            app.tableSelectionPopup.Tooltip = {'select table to show'};
            app.FieldDropDownLabel = uilabel(app.mainGridLayout);
            app.FieldDropDownLabel.Text = 'Field';
            app.nodesViewAdditionalField = uidropdown(app.mainGridLayout);
            app.nodesViewAdditionalField.Tag = 'nodesViewAdditionalField';
            app.jumpCheck = uicheckbox(app.mainGridLayout);
            app.jumpCheck.Tag = 'jumpCheck';
            app.jumpCheck.Tooltip = {'automatically jump to the selected annotation'};
            app.jumpCheck.Text = 'Auto jump';
            app.autoRefreshCheck = uicheckbox(app.mainGridLayout);
            app.autoRefreshCheck.Tag = 'autoRefreshCheck';
            app.autoRefreshCheck.Tooltip = {'auto update the tables, can be quite slow with many nodes'};
            app.autoRefreshCheck.Text = 'Auto refresh';
            app.nodesViewTable = uitable(app.mainGridLayout);
            app.nodesViewTable.ColumnName = {'NodeName'; 'Radius'; 'Z'; 'X'; 'Y'};
            app.nodesViewTable.RowName = {};
            app.nodesViewTable.Tag = 'nodesViewTable';
            app.loadBtn = uibutton(app.mainGridLayout, 'push');
            app.loadBtn.Tag = 'loadBtn';
            app.loadBtn.Icon = 'open_16px.png';
            app.loadBtn.Tooltip = {'import annotations from a file or from the main Matlab workspace'};
            app.loadBtn.Text = 'Load';
            app.saveBtn = uibutton(app.mainGridLayout, 'push');
            app.saveBtn.Tag = 'saveBtn';
            app.saveBtn.Icon = 'save_16px.png';
            app.saveBtn.Tooltip = {'export annotations to a file or to the main Matlab workspace'};
            app.saveBtn.Text = 'Save';
            app.helpBtn = uibutton(app.mainGridLayout, 'push');
            app.helpBtn.Tag = 'helpBtn';
            app.helpBtn.Icon = 'help_16px.png';
            app.helpBtn.Text = '';
            app.refreshBtn = uibutton(app.mainGridLayout, 'push');
            app.refreshBtn.Tag = 'refreshBtn';
            app.refreshBtn.Icon = 'refresh_16px.png';
            app.refreshBtn.Tooltip = {'refresh table'};
            app.refreshBtn.Text = 'Refresh';
            app.deleteBtn = uibutton(app.mainGridLayout, 'push');
            app.deleteBtn.Tag = 'deleteBtn';
            app.deleteBtn.Icon = 'remove_16px.png';
            app.deleteBtn.Text = 'Delete all';
            app.visualizeBtn = uibutton(app.mainGridLayout, 'push');
            app.visualizeBtn.Tag = 'visualizeBtn';
            app.visualizeBtn.Icon = 'volume_rendering_16px.png';
            app.visualizeBtn.Tooltip = {'show the graph in 3D on a separate figure'};
            app.visualizeBtn.Text = 'Visualize in 3D';
            app.settingsBtn = uibutton(app.mainGridLayout, 'push');
            app.settingsBtn.Tag = 'settingsBtn';
            app.settingsBtn.Icon = 'settings_16px.png';
            app.settingsBtn.Tooltip = {'update visualization settings'};
            app.settingsBtn.Text = '';
            app.edgesViewTable = uitable(app.Figure);
            app.edgesViewTable.ColumnName = {'Node1'; 'Node2'; 'Weight'; 'Length'};
            app.edgesViewTable.RowName = {};
            app.edgesViewTable.Tag = 'edgesViewTable';
            app.edgesViewAdditionalField = uidropdown(app.Figure);
            app.edgesViewAdditionalField.Tag = 'edgesViewAdditionalField';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lines3dDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end