classdef ResampleDatasetGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure                       matlab.ui.Figure
        mainGridLayout               matlab.ui.container.GridLayout
        cancelBtn                    matlab.ui.control.Button
        resampleBtn                  matlab.ui.control.Button
        helpButton                   matlab.ui.control.Button
        settingsPanel                matlab.ui.container.Panel
        settingsGridLayout           matlab.ui.container.GridLayout
        FixAspectRatio               matlab.ui.control.CheckBox
        XYZLabel                     matlab.ui.control.Label
        resetBtn                     matlab.ui.control.Button
        DepthLabel                   matlab.ui.control.Label
        HeightLabel                  matlab.ui.control.Label
        separatorPanel               matlab.ui.container.Panel
        VoxelZ                       matlab.ui.control.NumericEditField
        VoxelY                       matlab.ui.control.NumericEditField
        VoxelX                       matlab.ui.control.NumericEditField
        Percentage                   matlab.ui.control.NumericEditField
        DimensionZ                   matlab.ui.control.NumericEditField
        DimensionY                   matlab.ui.control.NumericEditField
        DimensionX                   matlab.ui.control.NumericEditField
        WidthLabel                   matlab.ui.control.Label
        VoxelsLabel                  matlab.ui.control.Label
        DimensionsLabel              matlab.ui.control.Label
        ResamplingMode               matlab.ui.container.ButtonGroup
        PercentageXY                 matlab.ui.control.RadioButton
        PercentageXYZ                matlab.ui.control.RadioButton
        Voxels                       matlab.ui.control.RadioButton
        Dimensions                   matlab.ui.control.RadioButton
        LabelsresampleDropDown       matlab.ui.control.DropDown
        LabelsresampleDropDownLabel  matlab.ui.control.Label
        ResamplingMethod             matlab.ui.control.DropDown
        ResamplingMethodLabel        matlab.ui.control.Label
        ResamplingFunction           matlab.ui.control.DropDown
        ResamplingFunctionLabel      matlab.ui.control.Label
        propertiesPanel              matlab.ui.container.Panel
        datasetGridLayout            matlab.ui.container.GridLayout
        colorsTxt                    matlab.ui.control.Label
        ColornoLabel                 matlab.ui.control.Label
        pixsizeZ                     matlab.ui.control.Label
        PixelsizeZLabel              matlab.ui.control.Label
        depthTxt                     matlab.ui.control.Label
        DepthpxLabel                 matlab.ui.control.Label
        pixsizeY                     matlab.ui.control.Label
        PixelsizeYLabel              matlab.ui.control.Label
        heightTxt                    matlab.ui.control.Label
        HeightpxLabel                matlab.ui.control.Label
        pixsizeX                     matlab.ui.control.Label
        PixelsizeXLabel              matlab.ui.control.Label
        widthTxt                     matlab.ui.control.Label
        WidthpxLabel                 matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        winController % controller of the dialog
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
            app.Figure.Visible = false;
            app.Figure.Icon = fullfile(app.winController.mibModel.mibPath, 'assets', 'icons', 'mib_icon_16px.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Resample dataset';
            app.mainGridLayout = uigridlayout(app.Figure);
            app.propertiesPanel = uipanel(app.mainGridLayout);
            app.propertiesPanel.Title = 'Dataset properties';
            app.datasetGridLayout = uigridlayout(app.propertiesPanel);
            app.Image = uiimage(app.datasetGridLayout);
            app.Image.ImageSource = 'resize_96px.png';
            app.WidthpxLabel = uilabel(app.datasetGridLayout);
            app.WidthpxLabel.Text = 'Width, px:';
            app.widthTxt = uilabel(app.datasetGridLayout);
            app.widthTxt.Text = '1024';
            app.PixelsizeXLabel = uilabel(app.datasetGridLayout);
            app.PixelsizeXLabel.Text = 'Pixel size, X:';
            app.pixsizeX = uilabel(app.datasetGridLayout);
            app.pixsizeX.Text = '0.013';
            app.HeightpxLabel = uilabel(app.datasetGridLayout);
            app.HeightpxLabel.Text = 'Height, px:';
            app.heightTxt = uilabel(app.datasetGridLayout);
            app.heightTxt.Text = '1024';
            app.PixelsizeYLabel = uilabel(app.datasetGridLayout);
            app.PixelsizeYLabel.Text = 'Pixel size, Y:';
            app.pixsizeY = uilabel(app.datasetGridLayout);
            app.pixsizeY.Text = '0.013';
            app.DepthpxLabel = uilabel(app.datasetGridLayout);
            app.DepthpxLabel.Text = 'Depth, px:';
            app.depthTxt = uilabel(app.datasetGridLayout);
            app.depthTxt.Text = '1024';
            app.PixelsizeZLabel = uilabel(app.datasetGridLayout);
            app.PixelsizeZLabel.Text = 'Pixel size, Z:';
            app.pixsizeZ = uilabel(app.datasetGridLayout);
            app.pixsizeZ.Text = '0.013';
            app.ColornoLabel = uilabel(app.datasetGridLayout);
            app.ColornoLabel.Text = 'Color, no:';
            app.colorsTxt = uilabel(app.datasetGridLayout);
            app.colorsTxt.Text = '3';
            app.settingsPanel = uipanel(app.mainGridLayout);
            app.settingsPanel.Title = 'Resampling settings';
            app.settingsGridLayout = uigridlayout(app.settingsPanel);
            app.ResamplingFunctionLabel = uilabel(app.settingsGridLayout);
            app.ResamplingFunctionLabel.Text = 'Resampling function';
            app.ResamplingFunction = uidropdown(app.settingsGridLayout);
            app.ResamplingFunction.Items = {'interpn', 'imresize', 'tformarray'};
            app.ResamplingFunction.Tooltip = {'define function that will be used for image resampling'};
            app.ResamplingFunction.Value = 'imresize';
            app.ResamplingMethodLabel = uilabel(app.settingsGridLayout);
            app.ResamplingMethodLabel.Text = 'Image resample';
            app.ResamplingMethod = uidropdown(app.settingsGridLayout);
            app.ResamplingMethod.Items = {'nearest', 'box', 'triangle', 'cubic', 'lanczos2', 'lanczos3'};
            app.ResamplingMethod.Tooltip = {'define resampling method for images'};
            app.ResamplingMethod.Value = 'cubic';
            app.LabelsresampleDropDownLabel = uilabel(app.settingsGridLayout);
            app.LabelsresampleDropDownLabel.Text = 'Labels resample';
            app.LabelsresampleDropDown = uidropdown(app.settingsGridLayout);
            app.LabelsresampleDropDown.Items = {'box'};
            app.LabelsresampleDropDown.Value = 'box';
            app.ResamplingMode = uibuttongroup(app.settingsGridLayout);
            app.Dimensions = uiradiobutton(app.ResamplingMode);
            app.Dimensions.Tooltip = {'resample the dataset using provided dimensions'};
            app.Dimensions.Text = 'Dimensions';
            app.Dimensions.Value = true;
            app.Voxels = uiradiobutton(app.ResamplingMode);
            app.Voxels.Tooltip = {'resample the dataset using provided voxel sizes'};
            app.Voxels.Text = 'Voxels';
            app.PercentageXYZ = uiradiobutton(app.ResamplingMode);
            app.PercentageXYZ.Tooltip = {'resample the dataset using provided relative percentage values after resampling'};
            app.PercentageXYZ.Text = 'Percentage XYZ';
            app.PercentageXY = uiradiobutton(app.ResamplingMode);
            app.PercentageXY.Tooltip = {'resample the dataset using provided relative percentage values after resampling'};
            app.PercentageXY.Text = 'Percentage XY';
            app.DimensionsLabel = uilabel(app.settingsGridLayout);
            app.DimensionsLabel.Text = 'Dimensions';
            app.VoxelsLabel = uilabel(app.settingsGridLayout);
            app.VoxelsLabel.Text = 'Voxels';
            app.WidthLabel = uilabel(app.settingsGridLayout);
            app.WidthLabel.Text = 'Width:';
            app.DimensionX = uieditfield(app.settingsGridLayout, 'numeric');
            app.DimensionX.ValueDisplayFormat = '%11d';
            app.DimensionX.Tooltip = {'define width of a resampled dataset in pixels'};
            app.DimensionY = uieditfield(app.settingsGridLayout, 'numeric');
            app.DimensionY.ValueDisplayFormat = '%11d';
            app.DimensionY.Tooltip = {'define height of a resampled dataset in pixels'};
            app.DimensionZ = uieditfield(app.settingsGridLayout, 'numeric');
            app.DimensionZ.ValueDisplayFormat = '%11d';
            app.DimensionZ.Tooltip = {'define depth of a resampled dataset in pixels'};
            app.Percentage = uieditfield(app.settingsGridLayout, 'numeric');
            app.Percentage.ValueDisplayFormat = '%11.3f';
            app.Percentage.Enable = 'off';
            app.Percentage.Tooltip = {'relative percentage for the resampled dataset'};
            app.VoxelX = uieditfield(app.settingsGridLayout, 'numeric');
            app.VoxelX.ValueDisplayFormat = '%11.3f';
            app.VoxelX.Enable = 'off';
            app.VoxelX.Tooltip = {'a new pixel size in X'};
            app.VoxelY = uieditfield(app.settingsGridLayout, 'numeric');
            app.VoxelY.ValueDisplayFormat = '%11.3f';
            app.VoxelY.Enable = 'off';
            app.VoxelY.Tooltip = {'a new pixel size in Y'};
            app.VoxelZ = uieditfield(app.settingsGridLayout, 'numeric');
            app.VoxelZ.ValueDisplayFormat = '%11.3f';
            app.VoxelZ.Enable = 'off';
            app.VoxelZ.Tooltip = {'a new pixel size in Z'};
            app.separatorPanel = uipanel(app.settingsGridLayout);
            app.HeightLabel = uilabel(app.settingsGridLayout);
            app.HeightLabel.Text = 'Height:';
            app.DepthLabel = uilabel(app.settingsGridLayout);
            app.DepthLabel.Text = 'Depth:';
            app.resetBtn = uibutton(app.settingsGridLayout, 'push');
            app.resetBtn.Icon = 'refresh_16px.png';
            app.resetBtn.Tooltip = {'кeset values to match the current dataset'};
            app.resetBtn.Text = 'Reset';
            app.XYZLabel = uilabel(app.settingsGridLayout);
            app.XYZLabel.Text = '%%, XYZ';
            app.FixAspectRatio = uicheckbox(app.settingsGridLayout);
            app.FixAspectRatio.Tooltip = {'fix aspect ratio when changing image size'};
            app.FixAspectRatio.Text = 'fix aspect';
            app.FixAspectRatio.Value = true;
            app.helpButton = uibutton(app.mainGridLayout, 'push');
            app.helpButton.Icon = 'help_16px.png';
            app.helpButton.Text = '';
            app.resampleBtn = uibutton(app.mainGridLayout, 'push');
            app.resampleBtn.Text = 'Resample';
            app.cancelBtn = uibutton(app.mainGridLayout, 'push');
            app.cancelBtn.Text = 'Close';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResampleDatasetGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end